classdef IHC_DAB_DETECTION_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SELECTASUITABLEMARKERDropDown  matlab.ui.control.DropDown
        SELECTASUITABLEMARKERDropDownLabel  matlab.ui.control.Label
        OCCUPANCYEditField             matlab.ui.control.NumericEditField
        OCCUPANCYEditFieldLabel        matlab.ui.control.Label
        DescribeLabel_3                matlab.ui.control.Label
        DescribeLabel_2                matlab.ui.control.Label
        DescribeLabel                  matlab.ui.control.Label
        TitleLabel                     matlab.ui.control.Label
        STARTANALYSISButton            matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: OCCUPANCYEditField, 
        % SELECTASUITABLEMARKERDropDown, STARTANALYSISButton
        function ROZPOCZNIJANALIZButtonPushed2(app, event)
            % change directory
            cd SCRIPTS\
            
            % add waitbar
            h = waitbar(0, 'Please wait...');
            
            % imread image
            [I, path] = uigetfile('*.jpg', 'Choose one image');
            str = strcat(path, I);
            image = imread(str);
            imagesc(app.UIAxes, image);
            
            % add waitbar
            waitbar(.1,h,'Please wait...');
            
            % function to detect DAB areas on immunohistochemical images
            gr_badawcza = app.SELECTASUITABLEMARKERDropDown.Value;
            [cluster_DAB, j_DAB, image_new_a, DAB_area] = DAB_detection_system_plastik(image, gr_badawcza);
            
            if isequal(cluster_DAB, 0)
                % add waitbar           
                waitbar(1,h,'Please wait...');

                % final results
                imagesc(app.UIAxes2, image);
                app.OCCUPANCYEditField.Value = DAB_area;
    
                close all
            else 
                % add waitbar
                waitbar(.7,h,'Please wait...');
    
                % outline the DAB areas from the selected cluster (last in order) on the original image
                BW_bound = imbinarize(cluster_DAB{j_DAB});
    
                [B_bound, ~, N_bound] = bwboundaries(BW_bound);
    
                imagesc(app.UIAxes2, image_new_a{2});
                hold(app.UIAxes2, 'on');
                for k = 1:length(B_bound)
                    boundary = B_bound{k};
                    if(k > N_bound)
                        % external edges
                        plot(app.UIAxes2, boundary(:,2), boundary(:,1), 'r', 'LineWidth', 1.5);
                    else
                        % internal edges
                        plot(app.UIAxes2, boundary(:,2), boundary(:,1), 'r', 'LineWidth', 1.5);
                    end
                end
                hold(app.UIAxes2, 'off');
                
                % add waitbar           
                waitbar(1,h,'Please wait...');
    
                % final results
                app.OCCUPANCYEditField.Value = DAB_area;
    
                close all
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.STARTANALYSISButton = uibutton(app.UIFigure, 'push');
            app.STARTANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @ROZPOCZNIJANALIZButtonPushed2, true);
            app.STARTANALYSISButton.Text = 'START ANALYSIS';
            app.TitleLabel = uilabel(app.UIFigure);
            app.TitleLabel.Text = 'APPLICATION FOR DETECTING AND CALCULATING THE PERCENTAGE OF DAB-STAINED TISSUE ON IMMUNOHISTOCHEMICAL IMAGES ';
            app.DescribeLabel = uilabel(app.UIFigure);
            app.DescribeLabel.Text = '';
            app.DescribeLabel_2 = uilabel(app.UIFigure);
            app.DescribeLabel_2.Text = 'ORIGINAL IMAGE';
            app.DescribeLabel_3 = uilabel(app.UIFigure);
            app.DescribeLabel_3.Text = 'RESULT';
            app.OCCUPANCYEditFieldLabel = uilabel(app.UIFigure);
            app.OCCUPANCYEditFieldLabel.Text = '% OCCUPANCY';
            app.OCCUPANCYEditField = uieditfield(app.UIFigure, 'numeric');
            app.OCCUPANCYEditField.ValueChangedFcn = createCallbackFcn(app, @ROZPOCZNIJANALIZButtonPushed2, true);
            app.SELECTASUITABLEMARKERDropDownLabel = uilabel(app.UIFigure);
            app.SELECTASUITABLEMARKERDropDownLabel.Text = 'SELECT A SUITABLE MARKER';
            app.SELECTASUITABLEMARKERDropDown = uidropdown(app.UIFigure);
            app.SELECTASUITABLEMARKERDropDown.Items = {'FLG', 'K10', 'Ki67', 'HSPA2', 'nowe_FLG'};
            app.SELECTASUITABLEMARKERDropDown.ValueChangedFcn = createCallbackFcn(app, @ROZPOCZNIJANALIZButtonPushed2, true);
            app.SELECTASUITABLEMARKERDropDown.Value = 'FLG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IHC_DAB_DETECTION_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end